classdef EEG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        RoboDocEEGLabel  matlab.ui.control.Label
        PlacetheEmotivInsightonyourheadLabel  matlab.ui.control.Label
        UIAxes           matlab.ui.control.UIAxes
        NextButton       matlab.ui.control.Button
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

% Remove title, axis labels, and tick labels
title(app.UIAxes, []);
xlabel(app.UIAxes, []);
ylabel(app.UIAxes, []);
app.UIAxes.XAxis.TickLabels = {};
app.UIAxes.YAxis.TickLabels = {};
% Display image and stretch to fill axes
I = imshow('wear_example1.jpg', 'Parent', app.UIAxes, ...
    'XData', [1 app.UIAxes.Position(3)], ...
    'YData', [1 app.UIAxes.Position(4)]);
% Set limits of axes
app.UIAxes.XLim = [0 I.XData(2)];
app.UIAxes.YLim = [0 I.YData(2)];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RoboDocEEGLabel = uilabel(app.UIFigure);
            app.RoboDocEEGLabel.Text = 'RoboDoc: EEG';
            app.PlacetheEmotivInsightonyourheadLabel = uilabel(app.UIFigure);
            app.PlacetheEmotivInsightonyourheadLabel.Text = 'Place the Emotiv Insight on your head';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.Text = 'Next';
        end
    end

    methods (Access = public)

        % Construct app
        function app = EEG

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end